classdef appej2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TIEMPOEditFieldLabel  matlab.ui.control.Label
        TIEMPOEditField       matlab.ui.control.NumericEditField
        V0EditFieldLabel      matlab.ui.control.Label
        V0EditField           matlab.ui.control.NumericEditField
        X0EditFieldLabel      matlab.ui.control.Label
        X0EditField           matlab.ui.control.NumericEditField
        Y0EditFieldLabel      matlab.ui.control.Label
        Y0EditField           matlab.ui.control.NumericEditField
        ThetaEditFieldLabel   matlab.ui.control.Label
        ThetaEditField        matlab.ui.control.NumericEditField
        PLOTButton            matlab.ui.control.Button
        AlturaMaximaLabel     matlab.ui.control.Label
        tEditFieldLabel       matlab.ui.control.Label
        tEditField            matlab.ui.control.NumericEditField
        CLEANButton           matlab.ui.control.Button
        TiempodevueloLabel    matlab.ui.control.Label
        tvueloEditFieldLabel  matlab.ui.control.Label
        tvueloEditField       matlab.ui.control.NumericEditField
        hEditFieldLabel       matlab.ui.control.Label
        hEditField            matlab.ui.control.NumericEditField
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TIEMPOEditField
        function TIEMPOEditFieldValueChanged(app, event)
            %value = app.TIEMPOEditField.Value;
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            [vectx,vecty] = f_graficar_proyectil(app.X0EditField.Value,app.Y0EditField.Value,app.V0EditField.Value,app.ThetaEditField.Value,app.TIEMPOEditField.Value);
            %plot(app.UIAxes,vectx,vecty);
            
            hold(app.UIAxes, 'on');
            [xmax,ymax,tmax] = f_calcular_altura_maxima(app.X0EditField.Value,app.Y0EditField.Value,app.V0EditField.Value,app.ThetaEditField.Value);
            plot(app.UIAxes,xmax,ymax,'.');
            hold(app.UIAxes, 'on');
            
            axis(app.UIAxes,[0 vectx(end) vecty(end) ymax]);
            for ii =1:length(vectx)
                plot(app.UIAxes,vectx(ii),vecty(ii),'.');
                hold(app.UIAxes, 'on');
                pause(0.01)
            end
            
            app.tEditField.Value = tmax;
            app.hEditField.Value = ymax;
            app.tvueloEditField.Value = f_calcular_t_vuelo(app.V0EditField.Value,app.ThetaEditField.Value);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            %f_graficar_movimiento_proyectil(app.TIEMPOEditField.Value,app.V0EditField.Value,app.V0enYEditField.Value,app.aenyEditField.Value,app.ThetaEditField.Value,app.X0EditField.Value,app.Y0EditField.Value);
        end

        % Button pushed function: CLEANButton
        function CLEANButtonPushed(app, event)
         hold(app.UIAxes, 'off');
         plot(app.UIAxes,1,1,'.','Color','w');   
         app.tEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TIEMPOEditFieldLabel = uilabel(app.UIFigure);
            app.TIEMPOEditFieldLabel.Text = 'TIEMPO';
            app.TIEMPOEditField = uieditfield(app.UIFigure, 'numeric');
            app.TIEMPOEditField.ValueChangedFcn = createCallbackFcn(app, @TIEMPOEditFieldValueChanged, true);
            app.TIEMPOEditField.Value = 1.3;
            app.V0EditFieldLabel = uilabel(app.UIFigure);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0EditField = uieditfield(app.UIFigure, 'numeric');
            app.V0EditField.Value = 9;
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.Value = 45;
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.AlturaMaximaLabel = uilabel(app.UIFigure);
            app.AlturaMaximaLabel.Text = 'Altura Maxima: ';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLEANButton = uibutton(app.UIFigure, 'push');
            app.CLEANButton.ButtonPushedFcn = createCallbackFcn(app, @CLEANButtonPushed, true);
            app.CLEANButton.Text = 'CLEAN';
            app.TiempodevueloLabel = uilabel(app.UIFigure);
            app.TiempodevueloLabel.Text = 'Tiempo de vuelo';
            app.tvueloEditFieldLabel = uilabel(app.UIFigure);
            app.tvueloEditFieldLabel.Text = 'tvuelo';
            app.tvueloEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Movimiento Proyectil')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appej2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end